classdef covid_visualization_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Hyperlink2                  matlab.ui.control.Hyperlink
        USCovid19DatalinkLabel      matlab.ui.control.Label
        ConfirmedCovid19Caseson3DGlobeButton  matlab.ui.control.Button
        Covid19DeathCaseson3DGlobeButton  matlab.ui.control.Button
        Covid19DeathCasesWorldWideButton  matlab.ui.control.Button
        PressbuttonButton           matlab.ui.control.Button
        ndCountryEditField          matlab.ui.control.EditField
        ndCountryLabel              matlab.ui.control.Label
        stCountryEditField          matlab.ui.control.EditField
        stCountryEditFieldLabel     matlab.ui.control.Label
        Covid19DatalinkLabel        matlab.ui.control.Label
        Hyperlink                   matlab.ui.control.Hyperlink
        GrowthofCasesofTop20USStatesButton  matlab.ui.control.Button
        Top20USStatesByCasesButton  matlab.ui.control.Button
        ConfirmedCovid19CasesintheUSAButton  matlab.ui.control.Button
        ConfirmedCovid19CasesWorldWideButton  matlab.ui.control.Button
        COVID19DataVisualizationAPictureofthePandemicLabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function results = func(app)
        % Helper function to reset globe view
            
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmedCovid19CasesWorldWideButton
        function ConfirmedCovid19CasesWorldWideButtonPushed(app, event)
%% Plotting Covid-19 data on Globe Map 
%Loading the cases 

opts = detectImportOptions("time_series_covid19_confirmed_global.csv", "TextType","string");
% Specifying the line number at which the variable namee is stored in the file.
opts.VariableNamesLine = 1;
% Specifying the lines in the file that contain data
opts.DataLines = [2,inf];
% Specifies whether to preserve the original variable names when importing the data
opts.PreserveVariableNames = true;
% Improting the Data 
Covid_Comfirmed = readtable("time_series_covid19_confirmed_global.csv",opts);
% Cleaning up the data by replacing certain wrong names with the normal
% country name
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "China") = "Mainland China";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Czechia") = "Czech Republic";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Iran (Islamic Republic of)") = "Iran";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Republic of Korea") = "Korea, South";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Republic of Moldova") = "Moldova";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Russian Federation") = "Russia";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Taipei and environs") = "Taiwan";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Taiwan*") = "Taiwan";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "United Kingdom") = "UK";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Viet Nam") = "Vietnam";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Province/State") == "St Martin") = "St Martin";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Province/State") == "Saint Barthelemy") = "Saint Barthelemy";
% Summing the data by Country/Region by summing the confirmed cases and averaging the latitudes and longitudes.
vars = Covid_Comfirmed.Properties.VariableNames;
Covid_Comfirmed_Country = groupsummary(Covid_Comfirmed,"Country/Region",{'sum','mean'},vars(3:end));
% Deleting unneccesary output contains unnecessary columns, such as sums of latitudes and longitudes or means of confirmed cases
vars = Covid_Comfirmed_Country.Properties.VariableNames;
vars = regexprep(vars,"^(sum_)(?=L(a|o))","remove_");
vars = regexprep(vars,"^(mean_)(?=[0-9])","remove_");
vars = erase(vars,{'sum_','mean_'});
Covid_Comfirmed_Country.Properties.VariableNames = vars;
Covid_Comfirmed_Country = removevars(Covid_Comfirmed_Country,[{'GroupCount'},vars(contains(vars,"remove_"))]);
%Excluding China Data because it is verylatge data 
Countries_exChina = Covid_Comfirmed_Country(Covid_Comfirmed_Country.("Country/Region") ~= "Mainland China",:);
vars = Countries_exChina.Properties.VariableNames;
%create a tiled chart layout, where multiple plots or tables can be arranged within a single figure window.
t = tiledlayout("flow");
%For loop 
for ii = [4, length(vars)]
    Countries_exChina.Category = categorical(repmat("<100",height(Countries_exChina),1));
    Countries_exChina.Category(table2array(Countries_exChina(:,ii)) >= 100) = ">=100";
    %move to the next tile in the tiled layout.
    nexttile
    table = Countries_exChina(:,[1:3, ii, end]);
    table(table.(4) == 0,:) = [];
    % geographic bubble chart
    gb = geobubble(table,"Lat","Long","SizeVariable",vars(ii),"ColorVariable","Category","Basemap","satellite");
    gb.BubbleColorList = [1,0,1;1,0,0];
    gb.LegendVisible = "off";
    gb.Title = "As of " + vars(ii);
    gb.SizeLimits = [0, max(Countries_exChina.(vars{length(vars)}))];
    gb.MapCenter = [21.6385   36.1666];
    gb.ZoomLevel = 0.3606;
end
% setting a tittle to the Graph 
title(t,[" Comfirmed COVID-19 Cases in the World Wide"; ...
    "Countries with <100 cases highlighted in Magenta"])

        end

        % Button pushed function: ConfirmedCovid19CasesintheUSAButton
        function ConfirmedCovid19CasesintheUSAButtonPushed(app, event)
%% Plotting US States Data 
% Import the data for US States 
opts = detectImportOptions("time_series_covid19_confirmed_US.csv", "TextType","string");
opts.VariableNamesLine = 1;
opts.DataLines = [2,inf];
opts.PreserveVariableNames = true;
Covid_conf_us = readtable("time_series_covid19_confirmed_US.csv",opts);
% Clean the Data of the Table
Covid_conf_us(:,1) = [];
Covid_conf_us(:,1) = [];
Covid_conf_us(:,1) = [];
Covid_conf_us(:,1) = [];
Covid_conf_us(:,1) = [];
Covid_conf_us(:,1) = [];
Covid_conf_us(:,2) = [];
Covid_conf_us(:,4) = [];
% Sum the Data of the Days 
vars_us = Covid_conf_us.Properties.VariableNames;
times_conf_us = groupsummary(Covid_conf_us,"Province_State",{'sum','mean'},vars_us(2:end));
% Deleting unneccesary output contain
%unnecessary columns, such as sums of latitudes and longitudes or means of confirmed cases
vars_us = times_conf_us.Properties.VariableNames;
vars_us = regexprep(vars_us,"^(sum_)(?=L(a|o))","remove_");
vars_us = regexprep(vars_us,"^(mean_)(?=[0-9])","remove_");
vars_us = erase(vars_us,{'sum_','mean_'});
times_conf_us.Properties.VariableNames = vars_us;
times_conf_us = removevars(times_conf_us,[{'GroupCount'},vars_us(contains(vars_us,"remove_"))]);
vars_us = times_conf_us.Properties.VariableNames; 
% Plotting the data using Geobubble
figure
t = tiledlayout("flow");
%For loop to plot the data based on the data length we have 
for jj = [4, length(vars_us)]
    times_conf_us.Category = categorical(repmat("<100",height(times_conf_us),1));
    times_conf_us.Category(table2array(times_conf_us(:,jj)) >= 100) = ">=100";
    nexttile
    table_US = times_conf_us(:,[1:4, jj, end]);
    table_US(table_US.(5) == 0,:) = [];
    gb = geobubble(table_US,"Lat","Long_","SizeVariable",vars_us(jj),"ColorVariable","Category","Basemap","landcover");
    gb.BubbleColorList = [1,0,1;1,0,0];
    gb.LegendVisible = "off";
    gb.Title = "As of " + vars_us(jj);
    gb.SizeLimits = [0, max(times_conf_us.(vars_us{length(vars_us)}))];
    gb.MapCenter = [44.9669 -113.6201];
    gb.ZoomLevel = 1.7678;
end
title(t,[" Comfirmed COVID-19 Cases in the US"; ...
    "State with <100 cases highlighted in Magenta"])
        end

        % Button pushed function: Top20USStatesByCasesButton
        function Top20USStatesByCasesButtonPushed(app, event)
%% Ranking Satates by Confirmed Cases
%Loading US  cases 
opts = detectImportOptions("us-states.csv", "TextType","string");
opts.VariableNamesLine = 1;
opts.DataLines = [2,inf];
opts.PreserveVariableNames = true;
usstates = readtable("us-states.csv",opts);
% Summing the Data 
StatesData = groupsummary(usstates,{'date','state'}, ...
    "sum",{'cases','deaths'});
StatesData.Properties.VariableNames = erase(StatesData.Properties.VariableNames,"sum_");
StatessLatest = groupsummary(StatesData,"state","max","cases");
StatessLatest.Properties.VariableNames = erase(StatessLatest.Properties.VariableNames,"max_");

% Rank the top 20 Us states by Confirmed Covid-19 cases 
[sorted,idx] = sort(StatessLatest.cases,'descend');
labels = StatessLatest.state(idx);
k = 20;
topK = sorted(1:k);
labelsK = labels(1:k);
figure
histogram('Categories',categorical(labelsK),"BinCounts",topK, ...
    "DisplayOrder","ascend","Orientation","horizontal")
xlabel("Total Cases")
ylabel("State")
title([compose("COVID-19 Confirmed Cases by US states - Top %d",k); ...
    "As of " + datestr(max(usstates.date))])
        end

        % Button pushed function: GrowthofCasesofTop20USStatesButton
        function GrowthofCasesofTop20USStatesButtonPushed(app, event)
%% Growth of Confirmed Cases by States
%Loading US  cases 
opts = detectImportOptions("us-states.csv", "TextType","string");
opts.VariableNamesLine = 1;
opts.DataLines = [2,inf];
opts.PreserveVariableNames = true;
usstates = readtable("us-states.csv",opts);
% Summing the Data 
StatesData = groupsummary(usstates,{'date','state'}, ...
    "sum",{'cases','deaths'});
StatesData.Properties.VariableNames = erase(StatesData.Properties.VariableNames,"sum_");
StatessLatest = groupsummary(StatesData,"state","max","cases");
StatessLatest.Properties.VariableNames = erase(StatessLatest.Properties.VariableNames,"max_");

% Let's rank the top 10
[sorted,idx] = sort(StatessLatest.cases,'descend');
labels = StatessLatest.state(idx);
k = 20;
topK = sorted(1:k);
labelsK = labels(1:k);
figure
plot(usstates.date(usstates.state == labelsK(2)), ...
    usstates.cases(usstates.state == labelsK(2)));
hold on
for ii = 1:length(labelsK)
    plot(usstates.date(usstates.state == labelsK(ii)), ...
        usstates.cases(usstates.state == labelsK(ii)),"LineWidth",1);
end
hold off
title(["COVID-19 Confirmed Cases";compose("Top %d in US states",k)])
legend(labelsK(1:end),"location","northwest")
xlabel("As of " + datestr(max(usstates.date)))
ylabel("Cases")

        end

        % Value changed function: stCountryEditField
        function stCountryEditFieldValueChanged(app, event)

        end

        % Button pushed function: PressbuttonButton
        function PressbuttonButtonPushed(app, event)
            %% Plotting the Growth of Covid-19 cases of a Specefic Country
First_country = app.stCountryEditField.Value;
Second_country =app.ndCountryEditField.Value;
 % Making a figure 
figure
%Loading the cases 
opts = detectImportOptions("time_series_covid19_confirmed_global.csv", "TextType","string");
% Specifying the line number at which the variable namee is stored in the file.
opts.VariableNamesLine = 1;
% Specifying the lines in the file that contain data
opts.DataLines = [2,inf];
% Specifies whether to preserve the original variable names when importing the data
opts.PreserveVariableNames = true;
% Improting the Data 
Covid_Comfirmed = readtable("time_series_covid19_confirmed_global.csv",opts);
% Cleaning up the data by replacing certain wrong names with the normal
% country name
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "China") = "Mainland China";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Czechia") = "Czech Republic";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Iran (Islamic Republic of)") = "Iran";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Republic of Korea") = "Korea, South";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Republic of Moldova") = "Moldova";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Russian Federation") = "Russia";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Taipei and environs") = "Taiwan";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Taiwan*") = "Taiwan";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "United Kingdom") = "UK";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Country/Region") == "Viet Nam") = "Vietnam";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Province/State") == "St Martin") = "St Martin";
Covid_Comfirmed.("Country/Region")(Covid_Comfirmed.("Province/State") == "Saint Barthelemy") = "Saint Barthelemy";
% Summing the data by Country/Region by summing the confirmed cases and averaging the latitudes and longitudes.
vars = Covid_Comfirmed.Properties.VariableNames;
Covid_Comfirmed_Country = groupsummary(Covid_Comfirmed,"Country/Region",{'sum','mean'},vars(3:end));
% Deleting unneccesary output contains unnecessary columns, such as sums of latitudes and longitudes or means of confirmed cases
vars = Covid_Comfirmed_Country.Properties.VariableNames;
vars = regexprep(vars,"^(sum_)(?=L(a|o))","remove_");
vars = regexprep(vars,"^(mean_)(?=[0-9])","remove_");
vars = erase(vars,{'sum_','mean_'});
Covid_Comfirmed_Country.Properties.VariableNames = vars;
Covid_Comfirmed_Country = removevars(Covid_Comfirmed_Country,[{'GroupCount'},vars(contains(vars,"remove_"))]);
%Excluding China Data because it is verylatge data 
Countries_exChina = Covid_Comfirmed_Country(Covid_Comfirmed_Country.("Country/Region") ~= "Mainland China",:);
Covid_Data = Countries_exChina; 
vars = Countries_exChina.Properties.VariableNames;     
%First_country = input('Enter name of the First country (Except China):','s');
country_Data = Countries_exChina(Countries_exChina.("Country/Region") == First_country,:);
%Cleaning the Data 
country_Data(:,1) = [];
country_Data(:,1) = [];
country_Data(:,1) = [];
% Making the Vriable a matrix
case_date = country_Data.Properties.VariableNames;
% Creating a date time vecotor 
for i = 1:length(case_date)
    Mytime(i) = datetime(datevec(case_date(i)));
end
%Convert all variables in country_Data to double data type
country_Data = varfun(@double,country_Data);
%Take the Data set and make a it a single type 
Country_Data_Fixed = country_Data.Variables;
Country_Data_Fixed=single(Country_Data_Fixed);
%Plotting the Data 
subplot(1,2,1);
plot(Mytime,Country_Data_Fixed,"LineWidth",2.5)
% Title,x, and y labeling
ylabel("Number of Cases","FontSize",12,FontSmoothing="on",LineStyle="-",FontName='Times New Roman')
xlabel("Date since 2020","FontSize",12,FontSmoothing="on",FontName='Times New Roman')
title(First_country,"FontSize",15,FontSmoothing="on",FontName='Times New Roman')
%%  Comparison Plotting for Two Countries
%Subplot for Comparison 
subplot(1,2,2);
%Second Country
country_Data_second = Countries_exChina(Countries_exChina.("Country/Region") == Second_country,:);
country_Data_second(:,1) = [];
country_Data_second(:,1) = [];
country_Data_second(:,1) = [];
% Making the Vriable a matrix
%Convert all variables in country_Data to double then single data type
country_Data_second = varfun(@double,country_Data_second);
country_Data_second = country_Data_second.Variables;
country_Data_second=single(country_Data_second);
plot(Mytime,Country_Data_Fixed,"LineWidth",2.5)
hold on 
% plot of the Country Comparison 
plot(Mytime,country_Data_second,"LineWidth",2.5)
ylabel("Number of Cases","FontSize",12,FontSmoothing="on",LineStyle="-",FontName='Times New Roman')
xlabel("Date since 2020","FontSize",12,FontSmoothing="on",FontName='Times New Roman')
axis tight
legend(First_country,Second_country)
tit_str = sprintf('%s vs %s',First_country,Second_country);
title(tit_str,"FontSize",15,FontSmoothing="on",FontName='Times New Roman')
        end

        % Button pushed function: Covid19DeathCasesWorldWideButton
        function Covid19DeathCasesWorldWideButtonPushed(app, event)
%% Plotting Covid-19 death cases on Globle Map 
%Loading the cases 
opts = detectImportOptions("time_series_covid19_deaths_global.csv", "TextType","string");
% Specifying the line number at which the variable namee is stored in the file.
opts.VariableNamesLine = 1;
% Specifying the lines in the file that contain data
opts.DataLines = [2,inf];
% Specifies whether to preserve the original variable names when importing the data
opts.PreserveVariableNames = true;
% Improting the Data 
Covid_Death = readtable("time_series_covid19_deaths_global.csv",opts);
% Cleaning up the data by replacing certain wrong names with the normal
% country name
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "China") = "Mainland China";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Czechia") = "Czech Republic";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Iran (Islamic Republic of)") = "Iran";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Republic of Korea") = "Korea, South";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Republic of Moldova") = "Moldova";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Russian Federation") = "Russia";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Taipei and environs") = "Taiwan";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Taiwan*") = "Taiwan";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "United Kingdom") = "UK";
Covid_Death.("Country/Region")(Covid_Death.("Country/Region") == "Viet Nam") = "Vietnam";
Covid_Death.("Country/Region")(Covid_Death.("Province/State") == "St Martin") = "St Martin";
Covid_Death.("Country/Region")(Covid_Death.("Province/State") == "Saint Barthelemy") = "Saint Barthelemy";
% Summing the data by Country/Region by summing the confirmed cases and averaging the latitudes and longitudes.
vars_Death = Covid_Death.Properties.VariableNames;
Covid_Dealth_Country = groupsummary(Covid_Death,"Country/Region",{'sum','mean'},vars_Death(3:end));
% Deleting unneccesary output contains unnecessary columns, such as sums of latitudes and longitudes or means of confirmed cases
vars_Death = Covid_Dealth_Country.Properties.VariableNames;
vars_Death = regexprep(vars_Death,"^(sum_)(?=L(a|o))","remove_");
vars_Death = regexprep(vars_Death,"^(mean_)(?=[0-9])","remove_");
vars_Death = erase(vars_Death,{'sum_','mean_'});
Covid_Dealth_Country.Properties.VariableNames = vars_Death;
Covid_Dealth_Country = removevars(Covid_Dealth_Country,[{'GroupCount'},vars_Death(contains(vars_Death,"remove_"))]);
%Excluding China Data because it is verylatge data 
Death_exChina = Covid_Dealth_Country(Covid_Dealth_Country.("Country/Region") ~= "Mainland China",:);
vars_Death = Death_exChina.Properties.VariableNames;
%create a tiled chart layout, where multiple plots or tables can be arranged within a single figure window.
t = tiledlayout("flow");
%For loop 
for mm = [4, length(vars_Death)]
    Death_exChina.Category = categorical(repmat("<100",height(Death_exChina),1));
    Death_exChina.Category(table2array(Death_exChina(:,mm)) >= 100) = ">=100";
    %move to the next tile in the tiled layout.
    nexttile
    table_dealth = Death_exChina(:,[1:3, mm, end]);
    table_dealth(table_dealth.(4) == 0,:) = [];
    % geographic bubble chart
    gb = geobubble(table_dealth,"Lat","Long","SizeVariable",vars_Death(mm),"ColorVariable","Category","Basemap","colorterrain");
    gb.BubbleColorList = [1,0,1;1,0,0];
    gb.LegendVisible = "off";
    gb.Title = "As of " + vars_Death(mm);
    gb.SizeLimits = [0, max(Death_exChina.(vars_Death{length(vars_Death)}))];
    gb.MapCenter = [21.6385   36.1666];
    gb.ZoomLevel = 0.3606;
end
% setting a tittle to the Graph 
title(t,[" Comfirmed COVID-19 Cases in the World Wide"; ...
    "Countries with <100 cases highlighted in Magenta"])

        end

        % Button pushed function: Covid19DeathCaseson3DGlobeButton
        function Covid19DeathCaseson3DGlobeButtonPushed(app, event)
    % Load COVID-19 data
    data = readtable("CovidDataFor3DPlots.csv");
    
    % Extract coordinates and death data
    lat = transpose(data.lat);
    lon = transpose(data.long);
    deaths = transpose(data.total_deaths);
    
    % Create enhanced UI figure
    uiFigure = uifigure('Name', 'COVID-19 Deaths 3D Visualization', ...
                        'Position', [100, 100, 1200, 800]);
    
    % Create header
    headerLabel = uilabel(uiFigure, ...
                         'Text', 'COVID-19 Deaths by Country - Interactive 3D Globe', ...
                         'Position', [400, 760, 400, 22], ...
                         'FontSize', 16, ...
                         'FontWeight', 'bold', ...
                         'HorizontalAlignment', 'center');
    
    % Create information panel
    infoPanel = uipanel(uiFigure, ...
                       'Title', 'Interaction Guide', ...
                       'Position', [20, 20, 250, 150]);
    
    % Add instructions
    uilabel(infoPanel, 'Text', '• Left-click + drag: Rotate globe', ...
           'Position', [10, 100, 230, 22]);
    uilabel(infoPanel, 'Text', '• Right-click + drag: Zoom in/out', ...
           'Position', [10, 75, 230, 22]);
    uilabel(infoPanel, 'Text', '• Double-click: Reset view', ...
           'Position', [10, 50, 230, 22]);
    uilabel(infoPanel, 'Text', '• Marker size indicates death count', ...
           'Position', [10, 25, 230, 22]);
    
    % Create the 3D globe
    globe = geoglobe(uiFigure, 'Basemap', 'darkwater');
    
    % Organize data by size
    sizeCat1 = []; % <1,000 deaths
    sizeCat2 = []; % 1,000-10,000 deaths
    sizeCat3 = []; % 10,000-100,000 deaths
    sizeCat4 = []; % 100,000-500,000 deaths
    sizeCat5 = []; % 500,000-1,000,000 deaths
    sizeCat6 = []; % >1,000,000 deaths
    
    % Group by categories
    for i = 1:length(lat)
        if deaths(i) >= 1000000
            sizeCat6 = [sizeCat6; lat(i), lon(i), deaths(i), i];
        elseif deaths(i) > 500000
            sizeCat5 = [sizeCat5; lat(i), lon(i), deaths(i), i];
        elseif deaths(i) >= 100000
            sizeCat4 = [sizeCat4; lat(i), lon(i), deaths(i), i];
        elseif deaths(i) >= 10000
            sizeCat3 = [sizeCat3; lat(i), lon(i), deaths(i), i];
        elseif deaths(i) >= 1000
            sizeCat2 = [sizeCat2; lat(i), lon(i), deaths(i), i];
        else
            sizeCat1 = [sizeCat1; lat(i), lon(i), deaths(i), i];
        end
    end
    
    % Plot in order from smallest to largest
    hold(globe, 'on');
    
    % Category 1 (smallest)
    for i = 1:size(sizeCat1, 1)
        altitude = 100000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat1(i,1), sizeCat1(i,2), altitude, 'ro', 'MarkerSize', 4);
    end
    
    % Category 2
    for i = 1:size(sizeCat2, 1)
        altitude = 105000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat2(i,1), sizeCat2(i,2), altitude, 'ro', 'MarkerSize', 8);
    end
    
    % Category 3
    for i = 1:size(sizeCat3, 1)
        altitude = 110000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat3(i,1), sizeCat3(i,2), altitude, 'ro', 'MarkerSize', 12);
    end
    
    % Category 4
    for i = 1:size(sizeCat4, 1)
        altitude = 115000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat4(i,1), sizeCat4(i,2), altitude, 'ro', 'MarkerSize', 20);
    end
    
    % Category 5
    for i = 1:size(sizeCat5, 1)
        altitude = 120000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat5(i,1), sizeCat5(i,2), altitude, 'ro', 'MarkerSize', 35);
    end
    
    % Category 6 (largest)
    for i = 1:size(sizeCat6, 1)
        altitude = 125000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat6(i,1), sizeCat6(i,2), altitude, 'ro', 'MarkerSize', 50);
    end
    
    hold(globe, 'off');
    
    % Create a reset view button
    resetButton = uibutton(uiFigure, 'push', ...
                         'Text', 'Reset View', ...
                         'Position', [480, 20, 100, 30]);
    
    % Set up button callback
    resetButton.ButtonPushedFcn = @(btn,event) camreset(globe);

        end

        % Button pushed function: ConfirmedCovid19Caseson3DGlobeButton
        function ConfirmedCovid19Caseson3DGlobeButtonPushed(app, event)
    % Load COVID-19 data
    data = readtable("CovidDataFor3DPlots.csv");
    
    % Extract coordinates and case data
    lat = transpose(data.lat);
    lon = transpose(data.long);
    cases = transpose(data.total_cases);
    
    % Create enhanced UI figure
    uiFigure = uifigure('Name', 'COVID-19 Cases 3D Visualization', ...
                    'Position', [100, 100, 1200, 800]);
    
    % Create header
    headerLabel = uilabel(uiFigure, ...
                         'Text', 'COVID-19 Cases by Country - Interactive 3D Globe', ...
                         'Position', [400, 760, 400, 22], ...
                         'FontSize', 16, ...
                         'FontWeight', 'bold', ...
                         'HorizontalAlignment', 'center');
    
    % Create information panel
    infoPanel = uipanel(uiFigure, ...
                       'Title', 'Interaction Guide', ...
                       'Position', [20, 20, 250, 150]);
    
    % Add instructions
    uilabel(infoPanel, 'Text', '• Left-click + drag: Rotate globe', ...
           'Position', [10, 100, 230, 22]);
    uilabel(infoPanel, 'Text', '• Right-click + drag: Zoom in/out', ...
           'Position', [10, 75, 230, 22]);
    uilabel(infoPanel, 'Text', '• Double-click: Reset view', ...
           'Position', [10, 50, 230, 22]);
    uilabel(infoPanel, 'Text', '• Marker size indicates case count', ...
           'Position', [10, 25, 230, 22]);
    
    % Create the 3D globe
    globe = geoglobe(uiFigure, 'Basemap', 'satellite');
    
    % Organize data by size
    sizeCat1 = []; % <300K cases
    sizeCat2 = []; % 300K-1M cases
    sizeCat3 = []; % 1M-10M cases
    sizeCat4 = []; % 10M-25M cases
    sizeCat5 = []; % 25M-100M cases
    sizeCat6 = []; % >100M cases
    
    % Group by categories
    for i = 1:length(lat)
        if cases(i) >= 100000000
            sizeCat6 = [sizeCat6; lat(i), lon(i), cases(i), i];
        elseif cases(i) >= 25000000
            sizeCat5 = [sizeCat5; lat(i), lon(i), cases(i), i];
        elseif cases(i) >= 10000000
            sizeCat4 = [sizeCat4; lat(i), lon(i), cases(i), i];
        elseif cases(i) >= 1000000
            sizeCat3 = [sizeCat3; lat(i), lon(i), cases(i), i];
        elseif cases(i) >= 300000
            sizeCat2 = [sizeCat2; lat(i), lon(i), cases(i), i];
        else
            sizeCat1 = [sizeCat1; lat(i), lon(i), cases(i), i];
        end
    end
    
    % Plot in order from smallest to largest
    hold(globe, 'on');
    
    % Category 1 (smallest)
    for i = 1:size(sizeCat1, 1)
        altitude = 100000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat1(i,1), sizeCat1(i,2), altitude, 'yo', 'MarkerSize', 4);
    end
    
    % Category 2
    for i = 1:size(sizeCat2, 1)
        altitude = 105000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat2(i,1), sizeCat2(i,2), altitude, 'yo', 'MarkerSize', 8);
    end
    
    % Category 3
    for i = 1:size(sizeCat3, 1)
        altitude = 110000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat3(i,1), sizeCat3(i,2), altitude, 'yo', 'MarkerSize', 12);
    end
    
    % Category 4
    for i = 1:size(sizeCat4, 1)
        altitude = 115000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat4(i,1), sizeCat4(i,2), altitude, 'yo', 'MarkerSize', 20);
    end
    
    % Category 5
    for i = 1:size(sizeCat5, 1)
        altitude = 120000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat5(i,1), sizeCat5(i,2), altitude, 'yo', 'MarkerSize', 35);
    end
    
    % Category 6 (largest)
    for i = 1:size(sizeCat6, 1)
        altitude = 125000 * (0.98 + 0.04 * rand());
        geoplot3(globe, sizeCat6(i,1), sizeCat6(i,2), altitude, 'yo', 'MarkerSize', 50);
    end
    
    hold(globe, 'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'covidblue_e.jpg');
            app.COVID19DataVisualizationAPictureofthePandemicLabel = uilabel(app.UIFigure);
            app.COVID19DataVisualizationAPictureofthePandemicLabel.Text = 'COVID-19 Data Visualization: A Picture of the Pandemic';
            app.ConfirmedCovid19CasesWorldWideButton = uibutton(app.UIFigure, 'push');
            app.ConfirmedCovid19CasesWorldWideButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmedCovid19CasesWorldWideButtonPushed, true);
            app.ConfirmedCovid19CasesWorldWideButton.Text = ' Confirmed Covid-19 Cases World Wide';
            app.ConfirmedCovid19CasesintheUSAButton = uibutton(app.UIFigure, 'push');
            app.ConfirmedCovid19CasesintheUSAButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmedCovid19CasesintheUSAButtonPushed, true);
            app.ConfirmedCovid19CasesintheUSAButton.Text = 'Confirmed Covid-19 Cases in the USA';
            app.Top20USStatesByCasesButton = uibutton(app.UIFigure, 'push');
            app.Top20USStatesByCasesButton.ButtonPushedFcn = createCallbackFcn(app, @Top20USStatesByCasesButtonPushed, true);
            app.Top20USStatesByCasesButton.Text = 'Top 20 US States By Cases';
            app.GrowthofCasesofTop20USStatesButton = uibutton(app.UIFigure, 'push');
            app.GrowthofCasesofTop20USStatesButton.ButtonPushedFcn = createCallbackFcn(app, @GrowthofCasesofTop20USStatesButtonPushed, true);
            app.GrowthofCasesofTop20USStatesButton.Text = ' Growth of Cases of Top 20 US States';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://github.com/CSSEGISandData/COVID-19/tree/master/csse_covid_19_data/csse_covid_19_time_series';
            app.Covid19DatalinkLabel = uilabel(app.UIFigure);
            app.Covid19DatalinkLabel.Text = 'Covid-19 Data link:';
            app.stCountryEditFieldLabel = uilabel(app.UIFigure);
            app.stCountryEditFieldLabel.Text = '1st Country ';
            app.stCountryEditField = uieditfield(app.UIFigure, 'text');
            app.stCountryEditField.ValueChangedFcn = createCallbackFcn(app, @stCountryEditFieldValueChanged, true);
            app.ndCountryLabel = uilabel(app.UIFigure);
            app.ndCountryLabel.Text = '2nd Country ';
            app.ndCountryEditField = uieditfield(app.UIFigure, 'text');
            app.PressbuttonButton = uibutton(app.UIFigure, 'push');
            app.PressbuttonButton.ButtonPushedFcn = createCallbackFcn(app, @PressbuttonButtonPushed, true);
            app.PressbuttonButton.Text = 'Press button ';
            app.Covid19DeathCasesWorldWideButton = uibutton(app.UIFigure, 'push');
            app.Covid19DeathCasesWorldWideButton.ButtonPushedFcn = createCallbackFcn(app, @Covid19DeathCasesWorldWideButtonPushed, true);
            app.Covid19DeathCasesWorldWideButton.Text = 'Covid-19 Death Cases World Wide ';
            app.Covid19DeathCaseson3DGlobeButton = uibutton(app.UIFigure, 'push');
            app.Covid19DeathCaseson3DGlobeButton.ButtonPushedFcn = createCallbackFcn(app, @Covid19DeathCaseson3DGlobeButtonPushed, true);
            app.Covid19DeathCaseson3DGlobeButton.Text = 'Covid-19 Death Cases on 3D Globe ';
            app.ConfirmedCovid19Caseson3DGlobeButton = uibutton(app.UIFigure, 'push');
            app.ConfirmedCovid19Caseson3DGlobeButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmedCovid19Caseson3DGlobeButtonPushed, true);
            app.ConfirmedCovid19Caseson3DGlobeButton.Text = 'Confirmed Covid-19 Cases on 3D Globe ';
            app.USCovid19DatalinkLabel = uilabel(app.UIFigure);
            app.USCovid19DatalinkLabel.Text = 'US Covid-19 Data link:';
            app.Hyperlink2 = uihyperlink(app.UIFigure);
            app.Hyperlink2.URL = 'https://github.com/nytimes/covid-19-data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_visualization_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end